classdef PID_dc_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        DirectionDropDown       matlab.ui.control.DropDown
        DirectionDropDownLabel  matlab.ui.control.Label
        StopButton              matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        RPM_TargetKnob          matlab.ui.control.Knob
        RPM_TargetKnobLabel     matlab.ui.control.Label
    end

    
properties (Access = private)
        % ===== Hardware =====
        a
        enc

        % ===== Pins / Settings =====
        COM   = "COM5"
        BOARD = "Nano3"

        ENA   = "D9"
        IN1   = "D8"
        IN2   = "D7"

        ENC_A = "D2"
        ENC_B = "D3"

        CPR_MOTOR_4X  = 64.0
        GEAR_RATIO    = 131.25
        RPM_AT_OUTPUT = true
        CPR_TOTAL

        SAMPLE_MS = 50
        TsTarget

        PWM_MIN = 0
        PWM_MAX = 255

        % ===== Control variables (linked to UI) =====
        SETPOINT_RPM = 50.0
        forwardDir   = true   % true=Forward, false=Reverse

        % ===== PID gains =====
        Kp = 5.0
        Ki = 3.0
        Kd = 0.03

        rpmAlpha = 0.25
        dAlpha   = 0.15
        I_LIMIT  = 200.0

        SOFTSTART_SEC = 0.4
        SOFTSTART_PWM = 50

        % ===== Runtime states =====
        tmr
        Running = false
        stopFlag = false

        t0
        tPrev
        firstSample = true

        lastCount
        integral  = 0.0
        prevError = 0.0
        dFiltered = 0.0
        rpmFilt   = 0.0
    end

    methods (Access = private)

        function controlStep(app)
            if ~app.Running || app.stopFlag
                return;
            end

            t = toc(app.t0);

            dt = t - app.tPrev;
            app.tPrev = t;
            if dt <= 0
                return;
            end

            % Encoder delta
            count = readCount(app.enc);
            delta = count - app.lastCount;
            app.lastCount = count;

            rpmRaw = (double(delta) * 60.0) / (double(app.CPR_TOTAL) * dt);
            rpmRawMag = abs(rpmRaw);

            % EMA filter
            app.rpmFilt = app.rpmAlpha*rpmRawMag + (1-app.rpmAlpha)*app.rpmFilt;

            % init to avoid derivative kick
            if app.firstSample
                app.rpmFilt = rpmRawMag;
                app.prevError = app.SETPOINT_RPM - app.rpmFilt;
                app.dFiltered = 0;
                app.integral = 0;
                app.firstSample = false;
            end

            % Soft-start + PID
            if t < app.SOFTSTART_SEC
                pwmCmd = app.SOFTSTART_PWM;
            else
                if app.SETPOINT_RPM < 0.5
                    app.integral = 0;
                    app.prevError = 0;
                    app.dFiltered = 0;
                    pwmCmd = 0;
                else
                    err = app.SETPOINT_RPM - app.rpmFilt;

                    app.integral = app.integral + err*dt;
                    app.integral = max(-app.I_LIMIT, min(app.I_LIMIT, app.integral));

                    d = (err - app.prevError)/dt;
                    app.dFiltered = app.dAlpha*d + (1-app.dAlpha)*app.dFiltered;

                    u = app.Kp*err + app.Ki*app.integral + app.Kd*app.dFiltered;
                    pwmCmd = round(u);

                    % Saturation + anti-windup
                    if pwmCmd > app.PWM_MAX
                        pwmCmd = app.PWM_MAX;
                        if err > 0
                            app.integral = app.integral - err*dt;
                        end
                    elseif pwmCmd < app.PWM_MIN
                        pwmCmd = app.PWM_MIN;
                        if err < 0
                            app.integral = app.integral - err*dt;
                        end
                    end

                    app.prevError = err;
                end
            end

            app.applyMotorPWM(pwmCmd);
        end

        function stopNow(app)
            app.stopFlag = true;

            if ~isempty(app.tmr) && isvalid(app.tmr)
                try
                    stop(app.tmr);
                catch
                end
            end

            % Motor OFF
            try
                app.applyMotorPWM(0);
            catch
            end

            app.Running = false;

            % UI enable/disable
            if ~isempty(app.RunButton) && isvalid(app.RunButton)
                app.RunButton.Enable = "on";
            end
            if ~isempty(app.StopButton) && isvalid(app.StopButton)
                app.StopButton.Enable = "off";
            end
        end

        function applyMotorPWM(app, pwm)
            pwm = max(app.PWM_MIN, min(app.PWM_MAX, pwm));

            if pwm == 0
                writeDigitalPin(app.a, app.IN1, 0);
                writeDigitalPin(app.a, app.IN2, 0);
                writePWMDutyCycle(app.a, app.ENA, 0);
                return;
            end

            if app.forwardDir
                writeDigitalPin(app.a, app.IN1, 1);
                writeDigitalPin(app.a, app.IN2, 0);
            else
                writeDigitalPin(app.a, app.IN1, 0);
                writeDigitalPin(app.a, app.IN2, 1);
            end

            writePWMDutyCycle(app.a, app.ENA, pwm/255);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
app.TsTarget = app.SAMPLE_MS/1000;

            % Derived CPR
            if app.RPM_AT_OUTPUT
                app.CPR_TOTAL = app.CPR_MOTOR_4X * app.GEAR_RATIO;
            else
                app.CPR_TOTAL = app.CPR_MOTOR_4X;
            end

            % Read initial UI values
            app.SETPOINT_RPM = app.RPM_TargetKnob.Value;
            app.forwardDir   = strcmpi(app.DirectionDropDown.Value,"Forward");

            % Connect Arduino once
            app.a = arduino(app.COM, app.BOARD, "Libraries", "rotaryEncoder");
            configurePin(app.a, app.ENA, "PWM");
            configurePin(app.a, app.IN1, "DigitalOutput");
            configurePin(app.a, app.IN2, "DigitalOutput");

            app.enc = rotaryEncoder(app.a, app.ENC_A, app.ENC_B, round(app.CPR_TOTAL));

            % Motor off
            app.applyMotorPWM(0);

            % Encoder baseline
            app.lastCount = readCount(app.enc);

            % Create timer (but don't start)
            app.tmr = timer( ...
                "ExecutionMode","fixedRate", ...
                "Period", app.TsTarget, ...
                "BusyMode","drop", ...
                "TimerFcn", @(~,~) app.controlStep() );

            % UI state
            app.StopButton.Enable = "off";
        end

        % Value changed function: RPM_TargetKnob
        function RPM_TargetKnobValueChanged(app, event)
            app.SETPOINT_RPM = app.RPM_TargetKnob.Value;

            if app.SETPOINT_RPM < 0.5
                app.integral = 0;
                app.prevError = 0;
                app.dFiltered = 0;
            end
        end

        % Value changed function: DirectionDropDown
        function DirectionDropDownValueChanged(app, event)
            app.forwardDir = strcmpi(app.DirectionDropDown.Value, "Forward");

            if app.Running
                app.applyMotorPWM(0);
                pause(0.05);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if app.Running
                return;
            end

            app.stopFlag = false;
            app.Running = true;

            % Reset timing
            app.t0 = tic;
            app.tPrev = toc(app.t0);

            % Reset PID states
            app.firstSample = true;
            app.integral = 0;
            app.prevError = 0;
            app.dFiltered = 0;
            app.rpmFilt = 0;

            % Reset encoder baseline
            app.lastCount = readCount(app.enc);

            % UI enable/disable
            app.RunButton.Enable = "off";
            app.StopButton.Enable = "on";

            start(app.tmr);
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.stopNow();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
             app.stopNow();

            % Clean timer
            if ~isempty(app.tmr) && isvalid(app.tmr)
                try
                    delete(app.tmr);
                catch
                end
            end

            delete(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.RPM_TargetKnobLabel = uilabel(app.UIFigure);
            app.RPM_TargetKnobLabel.Text = 'RPM_Target';
            app.RPM_TargetKnob = uiknob(app.UIFigure, 'continuous');
            app.RPM_TargetKnob.Limits = [0 80];
            app.RPM_TargetKnob.ValueChangedFcn = createCallbackFcn(app, @RPM_TargetKnobValueChanged, true);
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.DirectionDropDownLabel = uilabel(app.UIFigure);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.UIFigure);
            app.DirectionDropDown.Items = {'Forward', 'Reverse'};
            app.DirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @DirectionDropDownValueChanged, true);
            app.DirectionDropDown.Value = 'Forward';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PID_dc_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end